classdef Main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = public)
        dataTable table
    end
    
    properties (Access = private)
        isCollectingData logical = true
    end


    methods (Access = private)
        
        function readSerialData(app, ~, ~)
            % Read and process serial data
            rawData = readline(app.serialObj);
            if startsWith(rawData, ":")
                parsedData = sscanf(rawData, ':%c%04d%04d%04d%02d%02d%02d%02d%02d');
                if numel(parsedData) == 9
                    type = char(parsedData(1));
                    a = parsedData(2);
                    b = parsedData(3);
                    year = parsedData(4);
                    month = parsedData(5);
                    day = parsedData(6);
                    hour = parsedData(7);
                    minute = parsedData(8);
                    second = parsedData(9);

                    timestamp = datetime(year, month, day, hour, minute, second);
                    floatValue = a + b / 10000; % Convert to decimal

                    % Store data
                    newEntry = table(timestamp, type, floatValue, 'VariableNames', ["Timestamp", "Type", "Value"]);
                    app.dataTable = [app.dataTable; newEntry];

                    % Update GUI
                    app.UITable.Data = app.dataTable(end, :); % Show latest data
                end
            end
        end 


        function saveDataPeriodically(app)
            while app.isCollectingData
                pause(10); % Save every 10 seconds
                save("saved_data.mat", "app.dataTable");
            end
        end

        function closeRequest(app, ~)
            app.isCollectingData = false; % Stop saving loop
            configureCallback(app.serialObj, "off"); % Stop serial callback
            delete(app);
        end

    end

    methods (Access = public)
        function startupFcn(app)
            % Setup serial port
            app.serialObj = serialport("COM3", 115200);
            configureTerminator(app.serialObj, "LF");
            configureCallback(app.serialObj, "terminator", @(src, event) readSerialData(app));

            % Initialize table
            app.dataTable = table([], [], [], 'VariableNames', ["Timestamp", "Type", "Value"]);

            % Start background saving
            startSaving = @() saveDataPeriodically(app);
            parfeval(backgroundPool, startSaving, 0);
        end
% 
%         function SwitchButtonPushed(app, ~)
%             % Open HistoryApp while keeping MainApp running
%             HistoryApp(app);
%             app.UIFigure.Visible = 'off'; % Hide MainApp temporarily
%         end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end